classdef GradientPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImageListBox               matlab.ui.control.ListBox
        ImageListBoxLabel          matlab.ui.control.Label
        UpdateFilterRangeButton    matlab.ui.control.Button
        GranularityEditField       matlab.ui.control.NumericEditField
        GranularityEditFieldLabel  matlab.ui.control.Label
        ToBottomEditField          matlab.ui.control.NumericEditField
        ToBottomEditFieldLabel     matlab.ui.control.Label
        FromBottomEditField        matlab.ui.control.NumericEditField
        FromBottomEditFieldLabel   matlab.ui.control.Label
        ToTopEditField             matlab.ui.control.NumericEditField
        ToTopEditFieldLabel        matlab.ui.control.Label
        FromTopEditField           matlab.ui.control.NumericEditField
        FromTopEditFieldLabel      matlab.ui.control.Label
        OutputTextArea             matlab.ui.control.TextArea
        OutputTextAreaLabel        matlab.ui.control.Label
        ClearButton                matlab.ui.control.Button
        BothButton                 matlab.ui.control.Button
        HorizontalOnlyButton       matlab.ui.control.Button
        VerticleOnlyButton         matlab.ui.control.Button
        updateButton               matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        CloseButton                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hsvimage % Description
        hsvinf
        verflag = true;
        horzflg = true;
        div = 4;
        fileName = "";
    end
    
    methods (Access = private)
        
        function results = updateFilters(app)
            run('addPath.m');
            FromTop = app.FromTopEditField.Value*2*pi()/360.0;
            ToTop = app.ToTopEditField.Value*pi()/180.0;
            FromBot = app.FromBottomEditField.Value*pi()/180.0;
            ToBot = app.ToBottomEditField.Value*pi()/180.0;
            app.OutputTextArea.Value = "Running";
            app.hsvimage = imread(string(app.fileName));
            app.hsvimage = flipdim(app.hsvimage ,1); 
            app.hsvimage = im2double(app.hsvimage);
            app.hsvinf = size(app.hsvimage);
            app.UITable.Data = [];
            cla(app.UIAxes) 
            hold(app.UIAxes, 'on')
            for row = 1:app.div:app.hsvinf(1)
                for col = 1:app.div:app.hsvinf(2)
                   RGB =  app.hsvimage(row,col,1:3);
                   HSV = rgb2hsv(RGB);
                   %app.UITable.Data = [app.UITable.Data;row,col,RGB(1),RGB(2),RGB(3),HSV(1),HSV(2),HSV(3)]; 
                   if(row == 31 && col == 133)
                        row = 31;
                   end
                   if(RGB(1) ~= 0.0 || RGB(2) ~= 0.0 || RGB(3) ~= 0.0) 
                        ang = HSV(1)*(2*pi());
                        
                        if(ang >= FromTop && ang < ToTop)
                            quiver(app.UIAxes,col,row,cos(ang),sin(ang),4.0,'Color',RGB);
                        end
                        if(ang >= FromBot && ang < ToBot)
                            quiver(app.UIAxes,col,row,cos(ang),sin(ang),4.0,'Color',RGB);
                        end
                   end
                end
            end
            hold(app.UIAxes, 'off')
            grid(app.UIAxes, 'on')
            %plot(app.UIAxes,cr,cc,'.')
            app.OutputTextArea.Value = "Done";  
        end


        function results = update(app)
            run('addPath.m');
            app.OutputTextArea.Value = "Running";
            %app.hsvimage = imread("J:\RCCDData\perfdataM\HSVDataSimp.png");
            app.hsvimage = imread(string(app.fileName));
            app.hsvimage = flipdim(app.hsvimage ,1); 
            app.hsvimage = im2double(app.hsvimage);
            app.hsvinf = size(app.hsvimage);
            app.UITable.Data = [];
            cla(app.UIAxes) 
            hold(app.UIAxes, 'on')
            for row = 1:app.div:app.hsvinf(1)
                for col = 1:app.div:app.hsvinf(2)
                   RGB =  app.hsvimage(row,col,1:3);
                   HSV = rgb2hsv(RGB);
                   %app.UITable.Data = [app.UITable.Data;row,col,RGB(1),RGB(2),RGB(3),HSV(1),HSV(2),HSV(3)]; 
                   if(row == 31 && col == 133)
                        row = 31;
                   end
                   if(RGB(1) ~= 0.0 || RGB(2) ~= 0.0 || RGB(3) ~= 0.0) 
                        ang = HSV(1)*(2*pi());
                        %fprintf("HSV(1):%0.4f, RGB(1):%0.4f,Angle R:%0.4f\r\n",HSV(1),RGB(1),ang);
                        
                        if(app.verflag == true && app.horzflg == true)
                            quiver(app.UIAxes,col,row,cos(ang),sin(ang),4.0,'Color',RGB);
                        end
                        if(app.verflag == true && app.horzflg == false)
                            quiver(app.UIAxes,col,row,0.0,sin(ang),4.0,'Color',RGB);
                        end
                        if(app.verflag == false && app.horzflg == true)
                            quiver(app.UIAxes,col,row,cos(ang),0.0,4.0,'Color',RGB);
                        end
                    end
                end
            end
            hold(app.UIAxes, 'off')
            grid(app.UIAxes, 'on')
            %plot(app.UIAxes,cr,cc,'.')
            app.OutputTextArea.Value = "Done";  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %hsvimage = imread("J:\RCCDV10\matlab\MPS\images\LHSV.png");
          %app.update();
            [pfxdrv,pfxdir] = addPathCD('matlab/MPS/apps');
            app.GranularityEditField.Value = 4.0;
            app.div = 4.0;
            app.FromTopEditField.Value = 320.0;
            app.ToTopEditField.Value = 340.0;
            app.FromBottomEditField.Value = 20.0;
            app.ToBottomEditField.Value = 40.0;
            tstfl01 = strcat(pfxdrv,"/RCCDData/perfdataM/HSVDataSimp.png");
            tstfl02 = strcat(pfxdrv,"/RCCDData/perfdataM/FullFlowSlice.png");
            tstfl03 = strcat(pfxdrv,"/RCCDData/perfdataM/FullFlow.png");
            app.ImageListBox.Items = [tstfl01,tstfl02,tstfl03];
            app.fileName=app.ImageListBox.Items(1);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
             delete(app)
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
             app.update();
        end

        % Button pushed function: VerticleOnlyButton
        function VerticleOnlyButtonPushed(app, event)
            app.verflag = true;
            app.horzflg = false;
            app.update();
        end

        % Button pushed function: HorizontalOnlyButton
        function HorizontalOnlyButtonPushed(app, event)
            app.verflag = false;
            app.horzflg = true;
            app.update();
        end

        % Button pushed function: BothButton
        function BothButtonPushed(app, event)
            app.verflag = true;
            app.horzflg = true; 
            app.update();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes) 
        end

        % Value changed function: GranularityEditField
        function GranularityEditFieldValueChanged(app, event)
            app.div = app.GranularityEditField.Value;
            
        end

        % Button pushed function: UpdateFilterRangeButton
        function UpdateFilterRangeButtonPushed(app, event)
            app.updateFilters();
        end

        % Clicked callback: ImageListBox
        function ImageListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.fileName=app.ImageListBox.Items(item);
            app.OutputTextArea.Value = app.fileName;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'rw'; 'cl'; 'R'; 'G'; 'B'; 'H'; 'S'; 'V'};
            app.UITable.RowName = {};
            app.updateButton = uibutton(app.UIFigure, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.VerticleOnlyButton = uibutton(app.UIFigure, 'push');
            app.VerticleOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @VerticleOnlyButtonPushed, true);
            app.VerticleOnlyButton.Text = 'Verticle Only';
            app.HorizontalOnlyButton = uibutton(app.UIFigure, 'push');
            app.HorizontalOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalOnlyButtonPushed, true);
            app.HorizontalOnlyButton.Text = 'Horizontal Only';
            app.BothButton = uibutton(app.UIFigure, 'push');
            app.BothButton.ButtonPushedFcn = createCallbackFcn(app, @BothButtonPushed, true);
            app.BothButton.Text = 'Both';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.FromTopEditFieldLabel = uilabel(app.UIFigure);
            app.FromTopEditFieldLabel.Text = 'FromTop';
            app.FromTopEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToTopEditFieldLabel = uilabel(app.UIFigure);
            app.ToTopEditFieldLabel.Text = 'To Top';
            app.ToTopEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromBottomEditFieldLabel = uilabel(app.UIFigure);
            app.FromBottomEditFieldLabel.Text = 'From Bottom';
            app.FromBottomEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToBottomEditFieldLabel = uilabel(app.UIFigure);
            app.ToBottomEditFieldLabel.Text = 'To Bottom';
            app.ToBottomEditField = uieditfield(app.UIFigure, 'numeric');
            app.GranularityEditFieldLabel = uilabel(app.UIFigure);
            app.GranularityEditFieldLabel.Text = 'Granularity';
            app.GranularityEditField = uieditfield(app.UIFigure, 'numeric');
            app.GranularityEditField.ValueChangedFcn = createCallbackFcn(app, @GranularityEditFieldValueChanged, true);
            app.UpdateFilterRangeButton = uibutton(app.UIFigure, 'push');
            app.UpdateFilterRangeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFilterRangeButtonPushed, true);
            app.UpdateFilterRangeButton.Text = 'Update Filter Range';
            app.ImageListBoxLabel = uilabel(app.UIFigure);
            app.ImageListBoxLabel.Text = 'Image';
            app.ImageListBox = uilistbox(app.UIFigure);
            app.ImageListBox.ClickedFcn = createCallbackFcn(app, @ImageListBoxClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GradientPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end