classdef hsvtest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             plotradius = 100;
                plotvalue = 1;
                [x, y] = meshgrid(-plotradius:plotradius);  %get x,y coordinates of pixels in image
                [hue, saturation] = cart2pol(x, y);  %convert to angle, radius. Angle is the hue, radius is the saturation in the HSV cylinder
                hue = (hue + pi) / (2*pi); %rescale from -pi:pi to 0:1 since matlab use 0:1 for the range of the hue
                saturation = saturation / plotradius;  %rescale saturation to range 0:1. Not that anything above 1 is outside the cylinder
                value = ones(size(hue)) * plotvalue;  %set value constant for all points in the disk
                %now set points outside the disk (saturation >1) to white. That'd be a value of 1 and saturation of 0. hue doesn't matter
                outsidedisk = saturation > 1;
                saturation(outsidedisk) = 0;
                value(outsidedisk) = 1;
                %finally, convert hsv to rgb and plot
                
                app.Image.ImageSource = hsv2rgb(cat(3, hue, saturation, value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hsvtest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end