classdef main_HSV2Gradient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImageListBox                matlab.ui.control.ListBox
        ImageListBoxLabel           matlab.ui.control.Label
        OutputTextArea              matlab.ui.control.TextArea
        OutputTextAreaLabel         matlab.ui.control.Label
        TransformButton             matlab.ui.control.Button
        HSVDeg                      matlab.ui.control.TextArea
        AngleDegreesEditField       matlab.ui.control.NumericEditField
        AngleDegreesEditFieldLabel  matlab.ui.control.Label
        QuitButton                  matlab.ui.control.Button
        PasteButton                 matlab.ui.control.Button
        HSVImage                    matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hsvwheelsz; % Description
        hImage;
        cImage;
        fileName;
        centerx;
        centery;
        rsang;
        tstfl01;
        tstfl02;
        tstfl03;
        pfxdir;
        pfxdrv;
    end
    
    methods (Access = private)
        
        function results = UpdateSHVColor(app)
            valued = app.AngleDegreesEditField.Value;
            sang = valued/360.0;
            app.HSVDeg.BackgroundColor = hsv2rgb(cat(3, sang, 1.0, 1.0));
            app.rsang = valued*pi()/180.0; 
            imgfl = strcat(app.pfxdir,"matlab/MPS/images/HSV.png");
            app.cImage = imread(imgfl);
            app.hsvwheelsz = size(app.cImage);
            cla(app.UIAxes) 
            hold(app.UIAxes, 'on')
            app.OutputTextArea.Value = sprintf("Angle:%0.2f, NAngle:%0.4f, RAngle:%0.4f",valued,sang,app.rsang);  
            app.hsvwheelsz = size(app.cImage);
            app.hImage = imshow(app.cImage, 'Parent', app.UIAxes,'xdata',[30.0 app.hsvwheelsz(2)],'ydata',[5.0 app.hsvwheelsz(1)]);
            %app.hImage = imshow(app.cImage, 'Parent', app.UIAxes);
            app.centery = (app.hsvwheelsz(1)/2.0);
            app.centerx = (app.hsvwheelsz(2)/2.0);  
            %plot(app.UIAxes,[0.0,0.0],'*b');
            plot(app.UIAxes,app.centerx,app.centery,'*b');
            plot(app.UIAxes,app.centerx+250*cos(app.rsang),app.centery-250*sin(app.rsang),'*b');
            plot(app.UIAxes,[app.centerx,app.centerx+app.centerx*cos(app.rsang)],[app.centery,app.centery-app.centery*sin(app.rsang)],'-r');
            %plot(app.UIAxes,[app.centerx,app.centery],[app.centerx+100.0,app.centery+100],'-r');
            %axis(app.UIAxes, [0.0 app.hsvwheelsz(1) 0.0 app.hsvwheelsz(2)])
            hold(app.UIAxes, 'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                [app.pfxdrv,app.pfxdir] = addPathCD('matlab/MPS/apps');
                app.AngleDegreesEditField.Value = 90;
                app.UpdateSHVColor();
                app.tstfl01 = strcat(app.pfxdrv,"/RCCDData/perfdataM/HSVDataSimp.png");
                app.tstfl02 = strcat(app.pfxdrv,"/RCCDData/perfdataM/FullFlowSlice.png");
                app.tstfl03 = strcat(app.pfxdrv,"/RCCDData/perfdataM/FullFlow.png");
                app.ImageListBox.Items = [app.tstfl01,app.tstfl02,app.tstfl03];
                imgfl = strcat(app.pfxdir,"matlab\MPS\images\HSV.png");
                app.cImage = imread(imgfl);
                app.hImage = imshow(app.cImage, 'Parent', app.UIAxes);
                app.fileName=app.ImageListBox.Items(1);
                app.HSVImage.ImageSource = string(app.fileName);
 
 if 0
                plotradius = 100;
                plotvalue = 1;
                [x, y] = meshgrid(-plotradius:plotradius);  %get x,y coordinates of pixels in image
                [hue, saturation] = cart2pol(x, y);  %convert to angle, radius. Angle is the hue, radius is the saturation in the HSV cylinder
                hue = (hue + pi) / (2*pi); %rescale from -pi:pi to 0:1 since matlab use 0:1 for the range of the hue
                saturation = saturation / plotradius;  %rescale saturation to range 0:1. Not that anything above 1 is outside the cylinder
                value = ones(size(hue)) * plotvalue;  %set value constant for all points in the disk
                %now set points outside the disk (saturation >1) to white. That'd be a value of 1 and saturation of 0. hue doesn't matter
                outsidedisk = saturation > 1;
                saturation(outsidedisk) = 0;
                value(outsidedisk) = 1;
                %finally, convert hsv to rgb and plot
                rgb = hsv2rgb(cat(3, hue, saturation, value));
                imgfl = strcat(app.pfxdir,"matlab\MPS\images\HSV.png");
                app.cImage = imread(imgfl);
                app.HSVImage.ImageSource 
                app.hImage = imshow(app.cImage, 'Parent', app.UIAxes);
                end
                
               
        end

        % Button pushed function: PasteButton
        function PasteButtonPushed(app, event)
            app.HSVImage.ImageSource = "J:\RCCDData\perfdataM\FullFlowSlice.png";
        
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: AngleDegreesEditField
        function AngleDegreesEditFieldValueChanged(app, event)
           app.UpdateSHVColor();
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            %rgbColor = impixel(rgbImage, column, row);
            img = imread("J:\RCCDData\perfdataM\HSVDataSimp.png");
            hsvinf = size(img);
            app.OutputTextArea.Value = sprintf("Height:%d,Width:%d,Pix:%d",hsvinf(1),hsvinf(2),hsvinf(3));
            GradientPlot
        end

        % Value changed function: ImageListBox
        function ImageListBoxValueChanged(app, event)


        end

        % Clicked callback: ImageListBox
        function ImageListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.fileName=app.ImageListBox.Items(item);
            app.HSVImage.ImageSource = string(app.fileName);
            app.UpdateSHVColor();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HSVImage = uiimage(app.UIFigure);
            app.PasteButton = uibutton(app.UIFigure, 'push');
            app.PasteButton.ButtonPushedFcn = createCallbackFcn(app, @PasteButtonPushed, true);
            app.PasteButton.Text = 'Paste';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.AngleDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngleDegreesEditFieldLabel.Text = 'Angle(Degrees)';
            app.AngleDegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleDegreesEditField.ValueChangedFcn = createCallbackFcn(app, @AngleDegreesEditFieldValueChanged, true);
            app.HSVDeg = uitextarea(app.UIFigure);
            app.TransformButton = uibutton(app.UIFigure, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.ImageListBoxLabel = uilabel(app.UIFigure);
            app.ImageListBoxLabel.Text = 'Image';
            app.ImageListBox = uilistbox(app.UIFigure);
            app.ImageListBox.ValueChangedFcn = createCallbackFcn(app, @ImageListBoxValueChanged, true);
            app.ImageListBox.ClickedFcn = createCallbackFcn(app, @ImageListBoxClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_HSV2Gradient

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end